classdef spectrai < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        spectraiUIFigure                matlab.ui.Figure
        TrainingInferencePanel          matlab.ui.container.Panel
        TrainingInferenceOptionsButtonGroup  matlab.ui.container.ButtonGroup
        ApplyPreTrainedNetworkButton    matlab.ui.control.RadioButton
        TransferLearningButton          matlab.ui.control.RadioButton
        TrainFromScratchButton          matlab.ui.control.RadioButton
        LoadNetworkButton               matlab.ui.control.Button
        LoadNetworkEditField            matlab.ui.control.EditField
        DeepLearningTaskPanel           matlab.ui.container.Panel
        DeepLearningOptionsButtonGroup  matlab.ui.container.ButtonGroup
        DenoisingButton                 matlab.ui.control.RadioButton
        SuperResolutionButton           matlab.ui.control.RadioButton
        SegmentationButton              matlab.ui.control.RadioButton
        ClassificationButton            matlab.ui.control.RadioButton
        CalibrationButton               matlab.ui.control.RadioButton
        ClassesEditFieldLabel           matlab.ui.control.Label
        ClassesEditField                matlab.ui.control.NumericEditField
        DataManagerPanel                matlab.ui.container.Panel
        LoadTrainInputButton            matlab.ui.control.Button
        LoadValInputButton              matlab.ui.control.Button
        LoadTestInputButton             matlab.ui.control.Button
        ClearAllDataButton              matlab.ui.control.Button
        ShuffleDataCheckBox             matlab.ui.control.CheckBox
        SplitDataCheckBox               matlab.ui.control.CheckBox
        TrainSplitEditFieldLabel        matlab.ui.control.Label
        TrainSplitEditField             matlab.ui.control.NumericEditField
        ValSplitEditFieldLabel          matlab.ui.control.Label
        ValSplitEditField               matlab.ui.control.NumericEditField
        TestSplitEditFieldLabel         matlab.ui.control.Label
        TestSplitEditField              matlab.ui.control.NumericEditField
        DataAsDirectoryCheckBox         matlab.ui.control.CheckBox
        SeedEditField                   matlab.ui.control.NumericEditField
        SeedCheckBox                    matlab.ui.control.CheckBox
        LoadTrainTargetButton           matlab.ui.control.Button
        LoadTrainInputEditField         matlab.ui.control.EditField
        LoadTrainTargetEditField        matlab.ui.control.EditField
        LoadValTargetButton             matlab.ui.control.Button
        LoadValInputEditField           matlab.ui.control.EditField
        LoadValTargetEditField          matlab.ui.control.EditField
        LoadTestTargetButton            matlab.ui.control.Button
        LoadTestInputEditField          matlab.ui.control.EditField
        LoadTestTargetEditField         matlab.ui.control.EditField
        DataFormatButtonGroup           matlab.ui.container.ButtonGroup
        ImageHWCButton                  matlab.ui.control.RadioButton
        ImageCHWButton                  matlab.ui.control.RadioButton
        SpectraButton                   matlab.ui.control.RadioButton
        TrainingHyperparametersPanel    matlab.ui.container.Panel
        EpochsEditFieldLabel            matlab.ui.control.Label
        EpochsEditField                 matlab.ui.control.NumericEditField
        BatchSizeEditFieldLabel         matlab.ui.control.Label
        BatchSizeEditField              matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel      matlab.ui.control.Label
        LearningRateEditField           matlab.ui.control.NumericEditField
        InputImageSizeEditFieldLabel    matlab.ui.control.Label
        InputImageSizeEditField         matlab.ui.control.NumericEditField
        TargetImageSizeEditFieldLabel   matlab.ui.control.Label
        TargetImageSizeEditField        matlab.ui.control.NumericEditField
        SpectrumLengthEditFieldLabel    matlab.ui.control.Label
        SpectrumLengthEditField         matlab.ui.control.NumericEditField
        OptimizerDropDownLabel          matlab.ui.control.Label
        OptimizerDropDown               matlab.ui.control.DropDown
        LRSchedulerDropDownLabel        matlab.ui.control.Label
        LRSchedulerDropDown             matlab.ui.control.DropDown
        CriterionDropDownLabel          matlab.ui.control.Label
        CriterionDropDown               matlab.ui.control.DropDown
        NetworkHyperparametersPanel     matlab.ui.container.Panel
        NetworkDropDownLabel            matlab.ui.control.Label
        NetworkDropDown                 matlab.ui.control.DropDown
        ActivationDropDownLabel         matlab.ui.control.Label
        ActivationDropDown              matlab.ui.control.DropDown
        NormalizationDropDownLabel      matlab.ui.control.Label
        NormalizationDropDown           matlab.ui.control.DropDown
        DimSwitch                       matlab.ui.control.Switch
        TerminalOutputPanel             matlab.ui.container.Panel
        TerminalOutputTextArea          matlab.ui.control.TextArea
        TrainButton                     matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        ResultsPreviewCheckBox          matlab.ui.control.CheckBox
        ReportFreqEpochsEditFieldLabel  matlab.ui.control.Label
        ReportFreqEpochsEditField       matlab.ui.control.NumericEditField
        SaveFreqEpochsEditFieldLabel    matlab.ui.control.Label
        SaveFreqEpochsEditField         matlab.ui.control.NumericEditField
        EvaluateButton                  matlab.ui.control.Button
        spectraiLabel                   matlab.ui.control.Label
        DataAugmentationPanel           matlab.ui.container.Panel
        HorizontalFlipCheckBox          matlab.ui.control.CheckBox
        VerticalFlipCheckBox            matlab.ui.control.CheckBox
        RotationCheckBox                matlab.ui.control.CheckBox
        RandomCropCheckBox              matlab.ui.control.CheckBox
        SpectralShiftCheckBox           matlab.ui.control.CheckBox
        SpectralFlipCheckBox            matlab.ui.control.CheckBox
        SpectralBackgroundCheckBox      matlab.ui.control.CheckBox
        MixupCheckBox                   matlab.ui.control.CheckBox
        SpectralShiftEditField          matlab.ui.control.NumericEditField
        PreprocessingPanel              matlab.ui.container.Panel
        SpectralCropEditFieldLabel      matlab.ui.control.Label
        SpectralCropStartEditField      matlab.ui.control.NumericEditField
        BGSubtractionDropDownLabel      matlab.ui.control.Label
        BGSubtractionDropDown           matlab.ui.control.DropDown
        DataNormalizationDropDownLabel  matlab.ui.control.Label
        DataNormalizationDropDown       matlab.ui.control.DropDown
        toEditFieldLabel                matlab.ui.control.Label
        SpectralCropEndEditField        matlab.ui.control.NumericEditField
        PreviewSpectraButton            matlab.ui.control.Button
        PreviewImagesButton             matlab.ui.control.Button
        InputDisplay                    matlab.ui.control.UIAxes
        TrainingProgressDisplay         matlab.ui.control.UIAxes
        OutputDisplay                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        train_input_data = ""   % Train input data
        val_input_data = ""     % Validation input data
        test_input_data = ""    % Test input data
        train_target_data = ""  % Train target data
        val_target_data = ""    % Validation target data
        test_target_data = ""   % Test target data
        network                 % PyTorch network
        optimizer               % PyTorch optimizer
        scheduler               % PyTorch scheduler
        criterion               % PyTorch criterion
        epochs = [];            % PyTorch epochs
        train_metrics = {};     % PyTorch train metrics
        val_metrics = {};       % PyTorch validation metrics
        test_metrics = {};      % PyTorch test metrics
        train_loss = [];        % PyTorch train loss
        val_loss = [];          % PyTorch validation loss
        test_loss = [];         % PyTorch test loss
        data_format             % Input data format
        data_directory          % Load data from directory
        pretrained_network      % PyTorch pretrained network
        pretrained_classes      % PyTorch pretrained network classes
        training_options        % User-defined training options
        task_options            % User-defined task options
    end
    
    methods (Access = public)
        
        function Augmentation_List = SetupDataAgumentation(app)
%           Creates a python dictionary of user-defined data augmentations.
% 
%           Arguments:
%               horizontal_flip: horizontal flip check box value
%               vertical_flip: vertical flip check box value
%               rotation: rotation check box value
%               random_crop: random crop check box value
%               spectral_shift: spectral shift edit field value
%               spectral_flip: spectral flip check box value
%               spectral_background: spectral background check box value
%               horizontal_flip: horizontal flip check box value
%             
%           Returns:
%               Augmentation_List: a python dictionary that stores the
%               values of the input arguments

            Augmentation_List = py.dict(pyargs('horizontal_flip', app.HorizontalFlipCheckBox.Value, ...
                                        'vertical_flip', app.VerticalFlipCheckBox.Value, ...
                                        'rotation', app.RotationCheckBox.Value, ...
                                        'random_crop', app.RandomCropCheckBox.Value, ...
                                        'spectral_shift', app.SpectralShiftEditField.Value, ...
                                        'spectral_flip', app.SpectralFlipCheckBox.Value, ...
                                        'spectral_background', app.SpectralBackgroundCheckBox.Value, ...
                                        'mixup', app.MixupCheckBox.Value));
        end
        
        function Network_Hyperparameters = SetupNetworkHyperparameters(app)
%           Creates a python dictionary of user-defined network hyperparameters.
% 
%           Arguments:
%               network: network architecture drop down selection
%               dimension: 2D or 3D convolutions for images
%               activation: activation function drop down selection
%               normalization: normalization layer drop down selection
%            
%           Returns:
%               Network_Hyperparameters: a python dictionary that stores
%               the values of the input arguments

            Network_Hyperparameters = py.dict(pyargs('network', app.NetworkDropDown.Value, ...
                                                'dimension', app.DimSwitch.Value, ...
                                                'activation', app.ActivationDropDown.Value, ...
                                                'normalization', app.NormalizationDropDown.Value));
        end
        
        function Training_Hyperparameters = SetupTrainingHyperparameters(app)
%           Creates a python dictionary of user-defined training hyperparameters.
% 
%           Arguments:
%               epochs: number of training epochs edit field value
%               batch_size: batch size edit field value
%               learning_rate: learning rate edit field value
%               input_image_size: input image size edit field value
%               target_image_size: target image size edit field value
%               spectrum_length: spectrum length edit field value
%               optimizer: optimizer drop down selection
%               scheduler: learning rate scheduler drop down selection
%               criterion: criterion drop down selection
%             
%           Returns:
%               Training_Hyperparameters: a python dictionary that stores
%               the values of the input arguments

            Training_Hyperparameters = py.dict(pyargs('epochs', app.EpochsEditField.Value, ...
                                                'batch_size', app.BatchSizeEditField.Value, ...
                                                'learning_rate', app.LearningRateEditField.Value, ...
                                                'input_image_size', app.InputImageSizeEditField.Value, ...
                                                'target_image_size', app.TargetImageSizeEditField.Value, ...
                                                'spectrum_length', app.SpectrumLengthEditField.Value, ...
                                                'optimizer', app.OptimizerDropDown.Value, ...
                                                'scheduler', app.LRSchedulerDropDown.Value,...
                                                'criterion', app.CriterionDropDown.Value));
        end
        
        function Preprocessing_List = SetupPreprocessing(app)
%           Creates a python dictionary of user-defined preprocessing methods.
% 
%           Arguments:
%               spectral_crop_start: start spectral index for cropping
%               spectral_crop_end: end spectral index for cropping
%               background_subtraction: background subtraction method
%                     drop down selection
%               data_normalization: data normalization method drop down
%                     selection
%             
%           Returns:
%               Preprocessing_List: a python dictionary that stores
%               the values of the input arguments

            Preprocessing_List = py.dict(pyargs('spectral_crop_start', app.SpectralCropStartEditField.Value, ...
                                        'spectral_crop_end', app.SpectralCropEndEditField.Value, ...
                                        'background_subtraction', app.BGSubtractionDropDown.Value, ...
                                        'data_normalization', app.DataNormalizationDropDown.Value));
        end
        
        function SetupTrainingOptions(app)
%           Enables/disables functionality given training/inference selection.
% 
%           Description:
%               Given training/inference options selected by user,
%               enables/disables various appropriate GUI fields.

            if app.TrainFromScratchButton.Value
                app.LoadNetworkEditField.Value = '';
                app.pretrained_network = '';
                EnableHyperparameters(app, 1);
                EnableApplyOptions(app, 1);
                EnableNetworkLoading(app, 0);
                app.TrainButton.Enable = 1;
                app.EvaluateButton.Enable = 0;
                app.ApplyButton.Enable = 0;
                app.training_options = py.dict(pyargs('training_option', app.TrainingInferenceOptionsButtonGroup.SelectedObject.Text, ...
                        'pretrained_network', 'None', 'pretrained_classes', 'None'));
            elseif app.TransferLearningButton.Value
                EnableApplyOptions(app, 1);
                EnableNetworkLoading(app, 1);
                LoadPretrainedNetwork(app);
                app.TrainButton.Enable = 1;
                app.EvaluateButton.Enable = 0;
                app.ApplyButton.Enable = 0;
                app.training_options = py.dict(pyargs('training_option', app.TrainingInferenceOptionsButtonGroup.SelectedObject.Text, ...
                    'pretrained_network', app.pretrained_network, 'pretrained_classes', app.pretrained_classes));
            else % app.ApplyPreTrainedNetworkButton.Value
                EnableNetworkLoading(app, 1);      
                LoadPretrainedNetwork(app);
                EnableApplyOptions(app, 0);
                app.TrainButton.Enable = 0;
                app.EvaluateButton.Enable = 1;
                app.ApplyButton.Enable = 1;
                app.training_options = py.dict(pyargs('training_option', app.TrainingInferenceOptionsButtonGroup.SelectedObject.Text, ...
                    'pretrained_network', app.pretrained_network, 'pretrained_classes', app.pretrained_classes));
            end
        end
        
        function SetupDataFormat(app)
%           Updates avilable data augmentations for given data format.
% 
%           Description:
%               Given data format and training/inference options selected
%               by user, enables/disables appropriate data augmentations.

            app.data_format = app.DataFormatButtonGroup.SelectedObject.Text;
            if app.TransferLearningButton.Value || app.TrainFromScratchButton.Value
                if app.ImageHWCButton.Value || app.ImageCHWButton.Value
                    UpdateImageAugmentation(app, 'Images')
                elseif app.SpectraButton.Value
                    UpdateImageAugmentation(app, 'Spectra')
                end
            end
            Enable3DNetwork(app);
        end
                        
        function SetupDataOptions(app)
%           Controls enabling/disabling data split and shufffling inputs.
% 
%           Description:
%               Enables/disables data split edit fields and data
%               shuffling seed value edit field given user selection of
%               split data check box and shuffle data check box.

            if app.SplitDataCheckBox.Value
                app.TrainSplitEditField.Enable = 1;
                app.ValSplitEditField.Enable = 1;
                app.TestSplitEditField.Enable = 1;
            else
                app.TrainSplitEditField.Enable = 0;
                app.ValSplitEditField.Enable = 0;
                app.TestSplitEditField.Enable = 0;
            end
            
            if app.ShuffleDataCheckBox.Value
                app.SeedCheckBox.Enable = 1;
                app.SeedEditField.Enable = 1;
            else
                app.SeedCheckBox.Enable = 0;
                app.SeedEditField.Enable = 0;
            end

        end
        
        function DataManager_Options = SetupDataManagerOptions(app)
%           Creates a python dictionary of user-defined data manager options.
% 
%           Arguments:
%               data_format: user-defined data format
%               data_directory: flag indicating if data is stored as a
%                   single directory or a single file
%               train_input_data: input training data
%               val_input_data: input validation data
%               test_input_data: input testing data
%               train_target_data: target training data
%               val_target_data: target validation data
%               test_target_data: target testing data
%               shuffle: flag indicating whether to randomly shuffle data
%               seed: seed for random shuffling (if any)
%               train_split: percentage of dataset for training (if any)
%               val_split: percentage of dataset for validation (if any)
%               test_split: percentage of dataset for testing (if any)
%             
%           Returns:
%               DataManager_Options: a python dictionary that stores
%               the values of the input arguments

            DataManager_Options = py.dict(pyargs(...
                'data_format', 'None', 'data_directory', 'True', ...
                'train_input_data', 'None', 'val_input_data', 'None', ... 
                'test_input_data', 'None', 'train_target_data', 'None', ...
                'val_target_data', 'None', 'test_target_data', 'None', ...
                'shuffle', 'False', 'seed', 'None', 'train_split', ...
                'None', 'val_split', 'None', 'test_split', 'None'));
            
            if ~isempty(app.data_format)
                update(DataManager_Options, py.dict(pyargs('data_format', app.data_format)));
            end
            
            if ~isempty(app.data_directory)
                update(DataManager_Options, py.dict(pyargs('data_directory', app.data_directory)));
            end
            
            if ~isempty(app.train_input_data)
                update(DataManager_Options, py.dict(pyargs('train_input_data', app.train_input_data)));
            end
            
            if ~isempty(app.val_input_data)
                update(DataManager_Options, py.dict(pyargs('val_input_data', app.val_input_data)));
            end
            
            if ~isempty(app.test_input_data)
                update(DataManager_Options, py.dict(pyargs('test_input_data', app.test_input_data)));
            end
            
            if ~isempty(app.train_target_data)
                update(DataManager_Options, py.dict(pyargs('train_target_data', app.train_target_data)));
            end
            
            if ~isempty(app.val_target_data)
                update(DataManager_Options, py.dict(pyargs('val_target_data', app.val_target_data)));
            end
            
            if ~isempty(app.test_target_data)
                update(DataManager_Options, py.dict(pyargs('test_target_data', app.test_target_data)));
            end
            
            if app.ShuffleDataCheckBox.Value
                update(DataManager_Options, py.dict(pyargs('shuffle', 'True')));
                if app.SeedCheckBox.Value
                    update(DataManager_Options, py.dict(pyargs('seed', app.SeedEditField.Value)));
                end
            end
            
            if app.SplitDataCheckBox.Value
                update(DataManager_Options, py.dict(pyargs('train_split', app.TrainSplitEditField.Value)));
                update(DataManager_Options, py.dict(pyargs('val_split', app.ValSplitEditField.Value)));
                update(DataManager_Options, py.dict(pyargs('test_split', app.TestSplitEditField.Value)));
            end
        end
        
        function SetupTaskOptions(app)
%           Updates default parameter values given task and data format.
% 
%           Description:
%               Given task and data format selected by user, applies
%               suitable default parameter values and calls additional
%               functions to enable/disable various appropriate GUI
%               fields.

            UpdateNetworkSelection(app);
            UpdateCriterionSelection(app);
            UpdateDataLoading(app);
            if app.DenoisingButton.Value
                SetupDataFormat(app);
                UpdateDataFormat(app, 'Both');
                if app.ImageCHWButton.Value || app.ImageHWCButton.Value
                    UpdateImageAugmentation(app, 'Images')
                    app.InputImageSizeEditField.Value = 16;
                    app.TargetImageSizeEditField.Value = 64;
                    app.SpectrumLengthEditField.Value = 500;
                elseif app.SpectraButton.Value
                    UpdateImageAugmentation(app, 'Spectra')
                end
                app.LearningRateEditField.Value = 0.0005;
                app.SpectrumLengthEditField.Value = 500;
                app.NormalizationDropDown.Value = 'None';
                app.SpectralCropEndEditField.Value = 500;
                app.ClassesEditField.Value = 0;
                app.ClassesEditField.Enable = 0;
            elseif app.SuperResolutionButton.Value
                app.DataFormatButtonGroup.SelectedObject = app.ImageHWCButton;
                SetupDataFormat(app);
                UpdateDataFormat(app, 'Images');
                app.LearningRateEditField.Value = 0.00001;
                app.InputImageSizeEditField.Value = 16;
                app.TargetImageSizeEditField.Value = 64;
                app.SpectrumLengthEditField.Value = 500;
                app.NormalizationDropDown.Value = 'None';
                app.SpectralCropEndEditField.Value = 500;
                app.ClassesEditField.Value = 0;
                app.ClassesEditField.Enable = 0;
            elseif app.SegmentationButton.Value
                app.DataFormatButtonGroup.SelectedObject = app.ImageHWCButton;
                SetupDataFormat(app);
                UpdateDataFormat(app, 'Images');
                app.InputImageSizeEditField.Enable = 0;
                app.SpectrumLengthEditField.Value = 500;
                app.NormalizationDropDown.Value = 'None';
                app.SpectralCropEndEditField.Value = 500;
                app.ClassesEditField.Enable = 1;
                msg = {'For Classification or Segmentation, please enter a classes value in the classes edit field.', '', ...
                    'Classes value must be greater than one and equal to the number of classes present in your dataset.'};
                title = 'Update Classes Value';
                uiconfirm(app.spectraiUIFigure,msg,title,...
                           'Options',{'Continue'},...
                           'DefaultOption',1,'CancelOption',1);
            elseif app.ClassificationButton.Value
                SetupDataFormat(app);
                UpdateDataFormat(app, 'Both');
                
                if app.ImageCHWButton.Value || app.ImageHWCButton.Value
                    UpdateImageAugmentation(app, 'Images')
                    
                    app.TargetImageSizeEditField.Value = 16;
                    app.SpectrumLengthEditField.Value = 250;
                    app.SpectralCropEndEditField.Value = 250;
                elseif app.SpectraButton.Value
                    UpdateImageAugmentation(app, 'Spectra')
                end
                app.InputImageSizeEditField.Enable = 0;
                app.NormalizationDropDown.Value = 'BatchNorm';          
                app.ClassesEditField.Enable = 1;
                msg = {'For Classification or Segmentation, please enter a classes value in the classes edit field.', '',...
                    'Classes value must be greater than one and equal to the number of classes present in your dataset.'};
                title = 'Update Classes Value';
                uiconfirm(app.spectraiUIFigure,msg,title,...
                           'Options',{'Continue'},...
                           'DefaultOption',1,'CancelOption',1);
            elseif app.CalibrationButton.Value
                UpdateImageAugmentation(app, 0);
                UpdateDataFormat(app, 'Spectra');
                
                app.DataFormatButtonGroup.SelectedObject = app.SpectraButton;
                SetupDataFormat(app);

                app.LearningRateEditField.Value = 0.0005;            
                app.NormalizationDropDown.Value = 'None';
                app.ClassesEditField.Enable = 0;
            end
            UpdateTaskOptions(app);
            SetupTrainingHyperparameters(app);
            SetupNetworkHyperparameters(app);
        end
        
        function Task_Options = UpdateTaskOptions(app)
%           Creates a python dictionary of user-defined task options.
% 
%           Arguments:
%               task: deep learning task
%               classes: number of classes for classification or
%                   segmentation (ignored otherwise)
%             
%           Returns:
%               Task_Options: a python dictionary that stores the values
%               of the input arguments

            Task_Options = py.dict(pyargs('task', app.DeepLearningOptionsButtonGroup.SelectedObject.Text, ...
                    'classes', app.ClassesEditField.Value));
        end
        
        function UpdateTrainDataLoading(app, input, target)
%           Locks/unlocks train data loading options given user selections.
%
%           Arguments:
%               input: binary flag indicating locking or unlocking of input
%                   data fields
%               target: binary flag indicating locking or unlocking of 
%                   target data fields
            if input
                app.LoadTrainInputButton.Enable = 1;
                app.LoadTrainInputEditField.Enable = 1;
            else
                app.LoadTrainInputButton.Enable = 0;
                app.LoadTrainInputEditField.Enable = 0;
            end
            
            if target
                app.LoadTrainTargetButton.Enable = 1;
                app.LoadTrainTargetEditField.Enable = 1;
            else
                app.LoadTrainTargetButton.Enable = 0;
                app.LoadTrainTargetEditField.Enable = 0;
            end
        end
        
        function UpdateValDataLoading(app, input, target)
%           Locks/unlocks validation data loading options given user selections.
%
%           Arguments:
%               input: binary flag indicating locking or unlocking of input
%                   data fields
%               target: binary flag indicating locking or unlocking of 
%                   target data fields
            if input
                app.LoadValInputButton.Enable = 1;
                app.LoadValInputEditField.Enable = 1;
            else
                app.LoadValInputButton.Enable = 0;
                app.LoadValInputEditField.Enable = 0;
            end
            
            if target
                app.LoadValTargetButton.Enable = 1;
                app.LoadValTargetEditField.Enable = 1;
            else
                app.LoadValTargetButton.Enable = 0;
                app.LoadValTargetEditField.Enable = 0;
            end
        end 

        function UpdateTestDataLoading(app, input, target)
%           Locks/unlocks test data loading options given user selections.
%
%           Arguments:
%               input: binary flag indicating locking or unlocking of input
%                   data fields
%               target: binary flag indicating locking or unlocking of 
%                   target data fields
            if input
                app.LoadTestInputButton.Enable = 1;
                app.LoadTestInputEditField.Enable = 1;
            else
                app.LoadTestInputButton.Enable = 0;
                app.LoadTestInputEditField.Enable = 0;
            end
            
            if target
                app.LoadTestTargetButton.Enable = 1;
                app.LoadTestTargetEditField.Enable = 1;
            else
                app.LoadTestTargetButton.Enable = 0;
                app.LoadTestTargetEditField.Enable = 0;
            end
        end
        
        function UpdateDataLoading(app)
%           Locks/unlocks data loading options given user selections.
%
%           Arguments:
%               input: binary flag indicating locking or unlocking of input
%                   data fields
%               target: binary flag indicating locking or unlocking of 
%                   target data fields

            if app.DenoisingButton.Value || app.CalibrationButton.Value || app.SegmentationButton.Value
                if app.ApplyPreTrainedNetworkButton.Value
                    UpdateTrainDataLoading(app, 0, 0);
                    UpdateValDataLoading(app, 0, 0);
                    UpdateTestDataLoading(app, 1, 1);
                else
                    if app.SplitDataCheckBox.Value
                        UpdateTrainDataLoading(app, 1, 1);
                        UpdateValDataLoading(app, 0, 0);
                        UpdateTestDataLoading(app, 0, 0);
                    else
                        UpdateTrainDataLoading(app, 1, 1);
                        UpdateValDataLoading(app, 1, 1);
                        UpdateTestDataLoading(app, 0, 0);
                    end
                end
            elseif app.ClassificationButton.Value
                if app.ApplyPreTrainedNetworkButton.Value
                    UpdateTrainDataLoading(app, 0, 0);
                    UpdateValDataLoading(app, 0, 0);
                    UpdateTestDataLoading(app, 1, 0);
                else
                    if app.SplitDataCheckBox.Value
                        UpdateTrainDataLoading(app, 1, 0);
                        UpdateValDataLoading(app, 0, 0);
                        UpdateTestDataLoading(app, 0, 0);
                    else
                        UpdateTrainDataLoading(app, 1, 0);
                        UpdateValDataLoading(app, 1, 0);
                        UpdateTestDataLoading(app, 0, 0);
                    end
                end
            else
                if app.ApplyPreTrainedNetworkButton.Value
                    UpdateTrainDataLoading(app, 0, 0);
                    UpdateValDataLoading(app, 0, 0);
                    UpdateTestDataLoading(app, 0, 1);
                else
                    if app.SplitDataCheckBox.Value
                        UpdateTrainDataLoading(app, 0, 1);
                        UpdateValDataLoading(app, 0, 0);
                        UpdateTestDataLoading(app, 0, 0);
                    else
                        UpdateTrainDataLoading(app, 0, 1);
                        UpdateValDataLoading(app, 0, 1);
                        UpdateTestDataLoading(app, 0, 0);
                    end
                end
            end
        end
        
        function UpdateImageAugmentation(app, type)
%           Updates available data augmentations given data format.
% 
%           Description:
%               Updates available data augmentations and sets default batch
%               size given data format.
%
%           Arguments:
%               type: data format as either 'Images' or 'Spectra'
            if strcmp(type, 'Images')
                if app.ClassificationButton.Value || app.SegmentationButton.Value
                    app.InputImageSizeEditField.Enable = 0;
                else
                    app.InputImageSizeEditField.Enable = 1;
                end
                app.TargetImageSizeEditField.Enable = 1;
                app.HorizontalFlipCheckBox.Enable = 1;
                app.VerticalFlipCheckBox.Enable = 1;
                app.RotationCheckBox.Enable = 1;
                app.RandomCropCheckBox.Enable = 1;
                app.BatchSizeEditField.Value = 2;
            elseif strcmp(type, 'Spectra')
                app.InputImageSizeEditField.Enable = 0;
                app.TargetImageSizeEditField.Enable = 0;
                app.HorizontalFlipCheckBox.Enable = 0;
                app.VerticalFlipCheckBox.Enable = 0;
                app.RotationCheckBox.Enable = 0;
                app.RandomCropCheckBox.Enable = 0;
                app.BatchSizeEditField.Value = 64;
            end
        end
        
        function UpdateDataFormat(app, type)
%           Updates available data formats given selected task.
% 
%           Description:
%               Updates available data formats for a task selected by the 
%               user. 
%
%           Arguments:
%               type: data format as either 'Images', 'Spectra', or 'Both'

            if strcmp(type, 'Spectra')
                app.ImageCHWButton.Enable = 0;
                app.ImageHWCButton.Enable = 0;
                app.SpectraButton.Enable = 1;
                app.DataFormatButtonGroup.SelectedObject = app.SpectraButton;
            elseif strcmp(type, 'Images')
                app.ImageCHWButton.Enable = 1;
                app.ImageHWCButton.Enable = 1;
                app.SpectraButton.Enable = 0;
                if app.SpectraButton.Value
                    app.DataFormatButtonGroup.SelectedObject = app.ImageHWCButton;
                end
            elseif strcmp(type, 'Both')
                app.ImageCHWButton.Enable = 1;
                app.ImageHWCButton.Enable = 1;
                app.SpectraButton.Enable = 1;
            end
        end
        
        function UpdateNetworkSelection(app)
%           Updates list of available neural networks given selected task.

            if app.DenoisingButton.Value || app.CalibrationButton.Value
                app.NetworkDropDown.Items = {'ResUNet', 'UNet'};
                app.NetworkDropDown.Value = 'ResUNet';
            elseif app.ClassificationButton.Value
                app.NetworkDropDown.Items = {'ResNet', 'EfficientNet', 'ResUNet', 'UNet', 'DenseNet'};
                app.NetworkDropDown.Value = 'ResNet';
            elseif app.SuperResolutionButton.Value
                app.NetworkDropDown.Items = {'RCAN', 'ResUNet', 'UNet'};
                app.NetworkDropDown.Value = 'RCAN';
            elseif app.SegmentationButton.Value
                app.NetworkDropDown.Items = {'ResUNet', 'UNet', 'SegNet'};
                app.NetworkDropDown.Value = 'ResUNet';
            end
            Enable3DNetwork(app)
        end
        
        function UpdateCriterionSelection(app)
%           Updates list of available criterions given selected task.

            if  app.ClassificationButton.Value|| app.SegmentationButton.Value
                app.CriterionDropDown.Items = {'Cross Entropy', 'Binary Cross Entropy'};
                app.CriterionDropDown.Value = 'Cross Entropy';
            elseif app.SuperResolutionButton.Value || app.DenoisingButton.Value || app.CalibrationButton.Value
                app.CriterionDropDown.Items = {'L1', 'L2 / MSE'};
                app.CriterionDropDown.Value = 'L1';
            end
        end
        
        function LoadPretrainedNetwork(app)
%           Loads a neural network pre-trained using spectrai.
% 
%           Description:
%               Prompts the user to select a pre-trained neural network to
%               load for evaluation or transfer learning.

            app.LoadNetworkEditField.Value = '';
            app.pretrained_network = '';
            
            msg = {'Please select a pre-trained neural network.'};
            title = 'Load Pre-Trained Neural Network';
            selection = uiconfirm(app.spectraiUIFigure,msg,title,...
                       'Options',{'Continue','Cancel'},...
                       'DefaultOption',1,'CancelOption',2);
            
            if strcmp(selection, 'Continue')     
                try
                    [filename, pathname] = uigetfile('*.pt', 'Load file');
                    app.pretrained_network = strcat(pathname, filename);
                    app.LoadNetworkEditField.Value = filename;
                                       
                catch
                end
                pretrained = struct(py.trainer.load_pretrained(app.pretrained_network));
                ApplyPretrainedParameters(app, pretrained);
                EnableHyperparameters(app, 0); 
            end
        end
        
        function loaded_data = LoadInput(app, type)
%           Loads input data sample or directory.
% 
%           Description:
%               Prompts user to specify an file or directory for loading
%               and returns a structure array containing the data (if not a
%               directory), the path string, and the filename (if not a
%               directory).
%
%           Arguments:
%               type: flag indicating if input is target data
%
%           Returns:
%               loaded_data: structure array containing three fields
%                   data: data from sample or empty array if directory
%                   pathname: path to file or directory
%                   filename: data filename or empty array if directory

            try
                if app.DataAsDirectoryCheckBox.Value
                    pathname = uigetdir();
                    pathname = strcat(pathname, '\');
                    loaded_data = struct('data', [], 'pathname', pathname, 'filename', []);
                else
                    if app.SegmentationButton.Value && strcmp(type, 'Target')
                        [filename, pathname] = uigetfile({'*.png';'*.jpg'}, 'Load file');
                    elseif app.ClassificationButton.Value && strcmp(type, 'Target')
                        [filename, pathname] = uigetfile({'*.txt';'*.csv';'*.xls';'*.xlsx'}, 'Load file');
                    else
                        [filename, pathname] = uigetfile('*.mat', 'Load file');
                    end
                    file = load([pathname, filename]);
                    fn = fieldnames(file);
                    data = file.(fn{1});
                    pathname = strcat(pathname, filename);
                    loaded_data = struct('data', data, 'pathname', pathname, 'filename', filename);
                end
            catch
                disp('Error loading input')
            end
        end
        
        function ResetResults(app)
%           Clears stored PyTorch state_dicts and any stored loss metrics.

            app.network = '';
            app.optimizer = '';
            app.scheduler = '';
            app.criterion = '';
            app.train_metrics = [];
            app.val_metrics = [];
            app.test_metrics = [];
        end
        
        function ClearDataInputs(app)
%           Clears input data and data augmentation selections.

            app.train_input_data = '';
            app.val_input_data = '';
            app.test_input_data = '';
            app.train_target_data = '';
            app.val_target_data = '';
            app.test_target_data = '';
                        
            app.network = '';
            
            app.data_format = '';
            app.LoadNetworkEditField.Value = '';
            
            app.LoadTrainInputEditField.Value = '';
            app.LoadValInputEditField.Value = '';
            app.LoadTestInputEditField.Value = '';
            app.LoadTrainTargetEditField.Value = '';
            app.LoadValTargetEditField.Value = '';
            app.LoadTestTargetEditField.Value = '';
            
            app.HorizontalFlipCheckBox.Value = 0;
            app.VerticalFlipCheckBox.Value = 0;
            app.RotationCheckBox.Value = 0;
            app.RandomCropCheckBox.Value = 0;
            app.SpectralShiftCheckBox.Value = 0;
            app.SpectralShiftEditField.Value = 0;
            app.SpectralFlipCheckBox.Value = 0;
            app.SpectralBackgroundCheckBox.Value = 0;
            app.MixupCheckBox.Value = 0;
            
            app.DataNormalizationDropDown.Value = 'None';
            
            app.PreviewSpectraButton.Enable = 0;
            app.PreviewImagesButton.Enable = 0;
        end
        
        function ApplyPretrainedParameters(app, pretrained)
%           Applies hyperparameters of input pre-trained neural network.
% 
%           Description:
%               Applies pre-trained neural network options and
%               hyperparameters to all GUI fields to enable transfer
%               learning or evaluation.
%
%           Arguments:
%               pretrained: spectrai pre-trained neural network

            if strcmp(char(pretrained.task), 'Denoising')
                app.DeepLearningOptionsButtonGroup.SelectedObject = app.DenoisingButton;
            elseif strcmp(char(pretrained.task), 'Calibration')
                app.DeepLearningOptionsButtonGroup.SelectedObject = app.CalibrationButton;
            elseif strcmp(char(pretrained.task), 'Classification')
                app.DeepLearningOptionsButtonGroup.SelectedObject = app.ClassificationButton;
                app.ClassesEditField.Enable = 1;
            elseif strcmp(char(pretrained.task), 'Segmentation')
                app.DeepLearningOptionsButtonGroup.SelectedObject = app.SegmentationButton;
                app.ClassesEditField.Enable = 1;
            elseif strcmp(char(pretrained.task), 'Super-Resolution')
                app.DeepLearningOptionsButtonGroup.SelectedObject = app.SuperResolutionButton;
            end
            SetupTaskOptions(app);

            if strcmp(char(pretrained.data_format), 'Image: C, H, w')
                app.DataFormatButtonGroup.SelectedObject = app.ImageCHWButton;
            elseif strcmp(char(pretrained.data_format), 'Image: H, W, C')
                app.DataFormatButtonGroup.SelectedObject = app.ImageHWCButton;
            elseif strcmp(char(pretrained.data_format), 'Spectra')
                app.DataFormatButtonGroup.SelectedObject = app.SpectraButton;
            end
                    
            app.NetworkDropDown.Value = char(pretrained.architecture);
            app.NormalizationDropDown.Value = char(pretrained.normalization);
            app.ActivationDropDown.Value = char(pretrained.activation);
            
            app.pretrained_classes = double(pretrained.classes);
            app.ClassesEditField.Value = double(pretrained.classes);
            app.InputImageSizeEditField.Value = double(pretrained.input_image_size);
            app.TargetImageSizeEditField.Value = double(pretrained.target_image_size);
            app.SpectrumLengthEditField.Value = double(pretrained.channels);
        end
       
        function EnableHyperparameters(app, val)
%           Enables network options and parameters given input val.
%
%           Arguments:
%               val: binary flag indicating locking or unlocking

            app.NetworkDropDown.Enable = val;
            app.ActivationDropDown.Enable = val;
            app.NormalizationDropDown.Enable = val;
            app.InputImageSizeEditField.Enable = val;
            app.TargetImageSizeEditField.Enable = val;
            app.SpectrumLengthEditField.Enable = val;
            
            app.ImageCHWButton.Enable = val;
            app.ImageHWCButton.Enable = val;
            app.SpectraButton.Enable = val;
            
            app.SuperResolutionButton.Enable = val;
            app.CalibrationButton.Enable = val;
            app.ClassificationButton.Enable = val;
            app.DenoisingButton.Enable = val;
            app.SegmentationButton.Enable = val;
            app.SuperResolutionButton.Enable = val;
        end
                
        function EnableApplyOptions(app, val)
%           Locks/unlocks various parameters for neural network application.
% 
%           Description:
%               Locks/unlocks available input fields, data augmentations,
%               and training hyperparameters not used during neural network
%               application.
%
%           Arguments:
%               val: binary flag indicating locking or unlocking
            
            app.SplitDataCheckBox.Enable = val;
            
            % Enable/disable data augmentation
            app.HorizontalFlipCheckBox.Enable = val;
            app.VerticalFlipCheckBox.Enable = val;
            app.RotationCheckBox.Enable = val;
            app.RandomCropCheckBox.Enable = val;
            app.SpectralShiftCheckBox.Enable = val;
            app.SpectralFlipCheckBox.Enable = val;
            app.SpectralBackgroundCheckBox.Enable = val;
            app.MixupCheckBox.Enable = val;
            app.SpectralShiftEditField.Enable = val;
                        
            % Enable/disable training hyperparameters
            app.EpochsEditField.Enable = val;
            app.LearningRateEditField.Enable = val;
            app.OptimizerDropDown.Enable = val;
            app.LRSchedulerDropDown.Enable = val;
            app.CriterionDropDown.Enable = val;
        end
        
        function EnableNetworkLoading(app, val)
%           Enables/disables neural network loading functionality.

            app.LoadNetworkButton.Enable = val;
            app.LoadNetworkEditField.Enable = val;
        end
        
       function Enable3DNetwork(app)
%           Enables/disables 3D convolution functionality.

            if app.ImageCHWButton.Value || app.ImageHWCButton.Value
                if app.SuperResolutionButton.Value
                    app.DimSwitch.Enable = 0;
                elseif strcmp(app.NetworkDropDown.Value, 'ResNet')
                    app.DimSwitch.Enable = 1;
                elseif strcmp(app.NetworkDropDown.Value, 'SegNet')
                    app.DimSwitch.Enable = 1;
                elseif strcmp(app.NetworkDropDown.Value, 'DenseNet')
                    app.DimSwitch.Enable = 1;
                elseif strcmp(app.NetworkDropDown.Value, 'UNet') || strcmp(app.NetworkDropDown.Value, 'ResUNet')
                    if ~app.SuperResolutionButton.Value
                        app.DimSwitch.Enable = 1;
                    else
                        app.DimSwitch.Enable = 0;
                    end
                else
                    app.DimSwitch.Enable = 0;
                end
            else
                app.DimSwitch.Enable = 0;
            end
        end
        
       function EnablePreview(app)
%           Enables/disables data processing/augmentation preview.
            if ~app.ApplyPreTrainedNetworkButton.Value
                if app.DenoisingButton.Value || app.CalibrationButton.Value
                    if app.SpectraButton.Value
                        if app.train_input_data ~= "" && app.train_target_data ~= ""
                            app.PreviewSpectraButton.Enable = 1;
                        end
                    else
                        if app.train_input_data ~= "" && app.train_target_data ~= ""
                            app.PreviewSpectraButton.Enable = 1;
                            app.PreviewImagesButton.Enable = 1;
                        end
                    end
                elseif app.ClassificationButton.Value
                    if app.SpectraButton.Value
                        if app.train_input_data ~= ""
                            app.PreviewSpectraButton.Enable = 1;
                        end
                    else
                        if app.train_input_data ~= ""
                            app.PreviewSpectraButton.Enable = 1;
                            app.PreviewImagesButton.Enable = 1;
                        end
                    end
                elseif app.SegmentationButton.Value
                    if app.train_input_data ~= "" && app.train_target_data ~= ""
                        app.PreviewSpectraButton.Enable = 1;
                        app.PreviewImagesButton.Enable = 1;
                    end
                elseif app.SuperResolutionButton.Value
                    if app.train_target_data ~= ""
                        app.PreviewSpectraButton.Enable = 1;
                        app.PreviewImagesButton.Enable = 1;
                    end
                end
            end
        end 
        
        function PreviewResults(app, x, y, x_label, y_label)
%           Displays an input sample and corresponding target output.
% 
%           Description:
%               Given an input sample and corresponding target sample,
%               plots the input spectrum (or a random spectrum from an
%               input hyperspectral image) or displays the spectral image
%               and also displays the  corresponding target (either a
%               spectrum, a class, a segmentation mask, or a
%               hyperspectral image).
%
%           Arguments:
%               x: input sample
%               y: target output sample
%               x_label: label for input sample
%               y_label: label for target output sample

            if strcmp(app.data_format,'Spectra')
                PreviewSpectra(app, x, y, x_label, y_label)
            else
                PreviewImage(app, x, y, x_label, y_label)
            end
        end
        
        function PreviewSpectra(app, x, y, x_label, y_label)
%           Displays an input spectrum and corresponding target output.
% 
%           Description:
%               Given an input sample and corresponding target sample,
%               plots the input spectrum (or a random spectrum from an
%               input hyperspectral image) and also displays the
%               corresponding target (either a spectrum, a class, a 
%               segmentation mask, or a hyperspectral image).
%
%           Arguments:
%               x: input sample
%               y: target output sample
%               x_label: label for input sample
%               y_label: label for target output sample

            reset(app.InputDisplay);
            reset(app.OutputDisplay);
            if isempty(app.data_format)
                SetupDataFormat(app)
            end
            
            if strcmp(app.data_format,'Spectra')
                if app.ClassificationButton.Value
                    DisplayClassification(app, num2str(x), x_label, app.InputDisplay)
                    DisplayClassification(app, num2str(y), y_label, app.OutputDisplay)
                else
                    DisplaySpectrum(app, x, x_label, app.InputDisplay)
                    DisplaySpectrum(app, y, y_label, app.OutputDisplay)
                end
                drawnow
            else
                if app.SuperResolutionButton.Value
                    [spec_x, spec_y] = GetRandomSpectra(app, x, y);
                    DisplaySpectrum(app, spec_x, x_label, app.InputDisplay);
                    DisplaySpectrum(app, spec_y, y_label, app.OutputDisplay);
                elseif app.ClassificationButton.Value
                    DisplayClassification(app, num2str(x), x_label, app.InputDisplay);
                    DisplayClassification(app, num2str(y), y_label, app.OutputDisplay);
                else
                    [spec_x, ~] = GetRandomSpectra(app, x, 0);
                    DisplaySpectrum(app, spec_x, x_label, app.InputDisplay);
                    DisplayImage(app, double(y)', y_label, app.OutputDisplay);
                end
                drawnow
            end
        end
        
        function PreviewImage(app, x, y, x_label, y_label)
%           Displays an input spectral image and corresponding target output.
% 
%           Description:
%               Given an input sample and corresponding target sample,
%               displays the input image and also displays the 
%               corresponding target (either a class, a segmentation mask, 
%               or a hyperspectral image).
%
%           Arguments:
%               x: input sample
%               y: target output sample
%               x_label: label for input sample
%               y_label: label for target output sample

            reset(app.InputDisplay);
            reset(app.OutputDisplay);
            if isempty(app.data_format)
                SetupDataFormat(app)
            end
              
            if app.SegmentationButton.Value
                DisplayImage(app, sum(x,3), x_label, app.InputDisplay);
                DisplayImage(app, double(y), y_label, app.OutputDisplay);
            elseif app.ClassificationButton.Value
                DisplayClassification(app, num2str(x), x_label, app.InputDisplay)
                DisplayClassification(app, num2str(y), y_label, app.OutputDisplay)
            else
                DisplayImage(app, sum(x,3), x_label, app.InputDisplay);
                DisplayImage(app, sum(y,3), y_label, app.OutputDisplay);
            end
            drawnow
        end
                                        
        function DisplayImage(app, img, img_label, display)
%           Displays an input spectral image.
% 
%           Description:
%               Displays an input spectral image (img) with label
%               (img_label) to a given display (display).
%
%           Arguments:
%               img: input spectral image
%               img_label: label for input spectral image
%               display: display (figure) in which to show spectral image

            I = imagesc(display, img, 'XData', [1 display.Position(3)], 'YData',[1 display.Position(4)]);
            display.XLim = [0 I.XData(2)];
            display.YLim = [0 I.YData(2)];
            title(display, img_label, 'Color', [0.96,0.96,0.96])
        end
        
        function DisplaySpectrum(app, spec, spec_label, display)
%           Displays an input spectrum.
% 
%           Description:
%               Displays an input spectrum (spec) with label (spec_label)
%               to a given display (display).
%
%           Arguments:
%               spec: input spectrum
%               spec_label: label for input spectrum
%               display: display (figure) in which to show spectrum

            plot(display, 1:length(spec), spec)
            display.XLim = [0 length(spec)];
            title(display, spec_label, 'Color', [0.96,0.96,0.96])
            ylabel(display, 'Normalised Intensity (a.u.)', 'Color', [0.96,0.96,0.96])
            display.YTickLabelMode = 'auto';
            display.YColor = [0.96,0.96,0.96];
        end
           
        function DisplayClassification(app, class, label, display)
%           Displays an class value.
% 
%           Description:
%               Displays an input class value (class) as a string to a 
%               given display (display).
%
%           Arguments:
%               class: input class value (string)
%               display: display (figure) in which to show class

            try
                delete(findobj(display,'type','text'));
            catch 
            end
            title(display, label, 'Color', [0.96,0.96,0.96])
            text(display, 0.5, 0.5, class, 'HorizontalAlignment', 'center', 'FontSize', 50);
        end
        
        function [spec_x, spec_y] = GetRandomSpectra(app, x, y)
%           Returns a random pair of corresponding spectra.
% 
%           Description:
%               Returns a random pair of corresponding spectra between
%               input and target output spectral iamges.
%
%           Arguments:
%               x: input spectral image
%               y: target spectral image
%
%           Returns:
%               spec_x: random spectrum from x (corresponding to spec_y)
%               spec_y: random spectrum from y (corresponding to spec_x)

            coord1 = randi([1 app.TargetImageSizeEditField.Value]);
            coord2 = randi([1 app.TargetImageSizeEditField.Value]);
            
            if app.SuperResolutionButton.Value
                ratio = (app.InputImageSizeEditField.Value/app.TargetImageSizeEditField.Value);
                new_coord1 = min(max(floor(coord1*ratio),1),64);
                new_coord2 = min(max(floor(coord2*ratio),1),64);
                spec_x = squeeze(x(new_coord1,new_coord2,:));
                spec_y = squeeze(y(coord1,coord2,:));
            else
                spec_x = squeeze(x(coord1,coord2,:));
                spec_y = 0;
            end
            
        end
        
        function PlotLosses(app, epochs, train_loss, val_loss)
%           Plots training and/or validation losses at each reporting epoch.
%
%           Arguments:
%               epochs: number of elapsed epochs
%               train_loss: training loss values at each epoch
%               val_loss: validation loss values at each epoch

            lgnd = {};
            
            if ~isempty(train_loss)
                plot(app.TrainingProgressDisplay, epochs, train_loss)
                lgnd{end+1} = 'Train Loss';
                hold(app.TrainingProgressDisplay, 'on')
            end
            
            if ~isempty(val_loss)
                plot(app.TrainingProgressDisplay, epochs, val_loss)
                lgnd{end+1} = 'Validation Loss';
            end
            hold(app.TrainingProgressDisplay, 'off')
            legend(app.TrainingProgressDisplay, 'on');
            legend(app.TrainingProgressDisplay, lgnd)            
            ylabel(app.TrainingProgressDisplay, 'Loss', 'Color', [0.96,0.96,0.96])
            xlabel(app.TrainingProgressDisplay, 'Epochs', 'Color', [0.96,0.96,0.96])
            app.TrainingProgressDisplay.XTickMode = 'auto';
            app.TrainingProgressDisplay.YTickMode = 'auto';
            app.TrainingProgressDisplay.XTickLabelMode = 'auto';
            app.TrainingProgressDisplay.YTickLabelMode = 'auto';
            app.TrainingProgressDisplay.XColor = [0.96,0.96,0.96];
            app.TrainingProgressDisplay.YColor = [0.96,0.96,0.96];
        end
                
        function PrintTrainingDetails(app, train)
%           Prints key training details at start of training/evaluation.
%
%           Arguments:
%               train: flag indicating training or evaluation

            Training_Option = app.TrainingInferenceOptionsButtonGroup.SelectedObject.Text;
            Task = app.DeepLearningOptionsButtonGroup.SelectedObject.Text;

            app.TerminalOutputTextArea.Value{end+1} = '';
            app.TerminalOutputTextArea.Value{end+1} = strcat('Training/Inference Option:    ', Training_Option);
            app.TerminalOutputTextArea.Value{end+1} = strcat('Task:    ', Task);
            app.TerminalOutputTextArea.Value{end+1} = '';
            app.TerminalOutputTextArea.Value{end+1} = strcat('Network:    ',  app.NetworkDropDown.Value);
            if train
                app.TerminalOutputTextArea.Value{end+1} = strcat('Epochs:    ',  num2str(app.EpochsEditField.Value));
            end
            app.TerminalOutputTextArea.Value{end+1} = '';
        end
        
        function selection = DataInputInstructions(app)
%           Displays instructions for data input format given selected options.

            if app.DataAsDirectoryCheckBox.Value && app.SplitDataCheckBox.Value
                msg = {['Data Format: ', app.data_format], 'Data as Directory: Selected', 'Split Data: Selected', '', ...
                    'Data should be stored in a single directory as separate files.', '', ...
                    'Data will automatically be split into training, validation, and test sets.' };
            elseif app.DataAsDirectoryCheckBox.Value
                msg = {['Data Format: ', app.data_format], 'Data as Directory: Selected', 'Split Data: Not Selected', '', ...
                    'Data should be stored in a single directory as separate files.'};
            elseif app.SplitDataCheckBox.Value
                msg = {['Data Format: ', app.data_format], 'Data as Directory: Not Selected', 'Split Data: Selected', '', ...
                    'Data should be stored in a single file.', '', ...
                    'Data will automatically be split into training, validation, and test sets.' };
            else
                msg = {['Data Format: ', app.data_format], 'Data as Directory: Not Selected', 'Split Data: Not Selected', '', ...
                    'Data should be stored in a single file.'};
            end
            title = 'Confirm Data Input Settings';
            selection = uiconfirm(app.spectraiUIFigure,msg,title,...
                       'Options',{'Continue','Cancel'},...
                       'DefaultOption',1,'CancelOption',2);
        end
                        
        function Train(app, Training_Options, Task_Options, Network_Hyperparameters, ...
                    Training_Hyperparameters, Preprocessing, Data_Augmentation, DataManager_Options)
%           Trains a neural network in PyTorch given input parameters.
% 
%           Description:
%               Performs neural network training in PyTorch given
%               user-defined input parameters, plotting progress over time
%               and saving the final trained model to 
%               spectrai/spectrai/saved_models.
%
%           Arguments:
%               Training_Options: Python dictionary of training options
%               Task_Options: Python dictionary of task options
%               Network_Hyperparameters: Python dictionary of network
%                   hyperparameters
%               Training_Hyperparameters: Python dictionary of training
%                   hyperparameters
%               Preprocessing: Python dictionary of preprocessing options
%               Data_Augmentation: Python dictionary of data augmentation 
%                   options
%               DataManager_Options: Python dictionary of data manager 
%                   options
            
            app.network = 'None';
            app.optimizer = 'None';
            app.scheduler = 'None';
            max_epochs = app.EpochsEditField.Value;
            epoch_batch = app.ReportFreqEpochsEditField.Value;
            save_freq = app.SaveFreqEpochsEditField.Value;
            
            for epoch = 1:epoch_batch:max_epochs
                
                if max_epochs - epoch < epoch_batch
                    step = (max_epochs - epoch) + 1;
                else
                    step = epoch_batch;
                end
                epoch_start = tic;                
                output = py.trainer.train_epoch(Training_Options, Task_Options, Network_Hyperparameters, ...
                Training_Hyperparameters, Preprocessing, Data_Augmentation, DataManager_Options, pyargs(...
                'net_state_dict', app.network, 'optimizer_state_dict', app.optimizer, 'scheduler_state_dict', app.scheduler, ...
                'epochs', step, 'results_preview', app.ResultsPreviewCheckBox.Value, 'current_epoch', epoch, ...
                'max_epochs', max_epochs, 'save_frequency', save_freq));
                epoch_end = toc(epoch_start);
                
                app.network = output{'network'};
                app.optimizer = output{'optimizer'};
                app.scheduler = output{'scheduler'};
                app.criterion = output{'criterion'};
                app.pretrained_classes = double(output{'classes'});
                
                app.TerminalOutputTextArea.Value{end+1} = ['Epoch: ', num2str(epoch+step-1)];
                app.epochs = [app.epochs; epoch+step-1];
                
                if isa(output{'train_metrics'}, 'py.dict')
                    app.train_metrics{end+1} = output{'train_metrics'};
                    app.train_loss = [app.train_loss; double(output{'train_metrics'}{'loss'})];
                    app.TerminalOutputTextArea.Value{end+1} = ['Train Loss: ', num2str(double(output{'train_metrics'}{'loss'}))];
                end
                
                if isa(output{'val_metrics'}, 'py.dict')
                    app.val_metrics{end+1} = output{'val_metrics'};
                    app.val_loss = [app.val_loss; double(output{'val_metrics'}{'loss'})];
                    app.TerminalOutputTextArea.Value{end+1} = ['Validation Loss: ', num2str(double(output{'val_metrics'}{'loss'}))];
                end
                app.TerminalOutputTextArea.Value{end+1} = ['Time: ', char(duration([0, 0, epoch_end]))];
                app.TerminalOutputTextArea.Value{end+1} = '';
                                           
                PlotLosses(app, app.epochs, app.train_loss, app.val_loss)
                                
                drawnow;
                
                if app.ResultsPreviewCheckBox.Value
                    preview_output = output{'preview'};
                    %x = double(preview_output{'input'});
                    y = double(preview_output{'output'});
                    target = double(preview_output{'target'});
                    PreviewResults(app, y, target, 'Network Output', 'Target')
                end
            end
        end
                
        function Apply(app, Training_Options, Task_Options, Network_Hyperparameters, ...
                    Training_Hyperparameters, Preprocessing, Data_Augmentation, DataManager_Options)
%           Applies a neural network in PyTorch given input parameters.
% 
%           Description:
%               Performs neural network application in PyTorch given
%               user-defined input parameters, and saving the application  
%               outputs to spectrai/spectrai/apply_outputs.
%
%           Arguments:
%               Training_Options: Python dictionary of training options
%               Task_Options: Python dictionary of task options
%               Network_Hyperparameters: Python dictionary of network
%                   hyperparameters
%               Training_Hyperparameters: Python dictionary of training
%                   hyperparameters
%               Preprocessing: Python dictionary of preprocessing options
%               Data_Augmentation: Python dictionary of data augmentation 
%                   options
%               DataManager_Options: Python dictionary of data manager 
%                   options
                                
            output = py.trainer.apply_pretrained(Training_Options, Task_Options, Network_Hyperparameters, ...
            Training_Hyperparameters, Preprocessing, Data_Augmentation, DataManager_Options, pyargs(...
            'net_state_dict', app.network, 'results_preview', app.ResultsPreviewCheckBox.Value));
                        
            if app.ResultsPreviewCheckBox.Value
                preview_output = output{'preview'};
                x = double(preview_output{'input'});
                y = double(preview_output{'output'});
                PreviewResults(app, x, y, 'Input', 'Network Output')
            end
        end
        
        function Evaluate(app, Training_Options, Task_Options, Network_Hyperparameters, ...
                    Training_Hyperparameters, Preprocessing, Data_Augmentation, DataManager_Options)
%           Evaluates a neural network in PyTorch given input parameters.
% 
%           Description:
%               Performs neural network evaluation in PyTorch given
%               user-defined input parameters.
%
%           Arguments:
%               Training_Options: Python dictionary of training options
%               Task_Options: Python dictionary of task options
%               Network_Hyperparameters: Python dictionary of network
%                   hyperparameters
%               Training_Hyperparameters: Python dictionary of training
%                   hyperparameters
%               Preprocessing: Python dictionary of preprocessing options
%               Data_Augmentation: Python dictionary of data augmentation 
%                   options
%               DataManager_Options: Python dictionary of data manager 
%                   options
                                
            output = py.trainer.evaluate_pretrained(Training_Options, Task_Options, Network_Hyperparameters, ...
            Training_Hyperparameters, Preprocessing, Data_Augmentation, DataManager_Options, pyargs(...
            'net_state_dict', app.network, 'results_preview', app.ResultsPreviewCheckBox.Value));
                        
            if isa(output{'test_metrics'}, 'py.dict')
                app.test_metrics{end+1} = output{'test_metrics'};
                app.test_loss = [app.test_loss; double(output{'test_metrics'}{'loss'})];
                app.TerminalOutputTextArea.Value{end+1} = ['Test Loss: ', num2str(double(output{'test_metrics'}{'loss'}))];
            end
                        
            if app.ResultsPreviewCheckBox.Value
                preview_output = output{'preview'};
                x = double(preview_output{'input'});
                y = double(preview_output{'output'});
                target = double(preview_output{'target'});
                PreviewResults(app, y, target, 'Network Output', 'Target')
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%           Initialises default parameters and settings.

            SetupTrainingOptions(app);
            SetupTaskOptions(app);
            SetupDataFormat(app);
            SetupDataOptions(app);
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
%           Trains a neural network in PyTorch given input parameters.
% 
%           Description:
%               Performs neural network training in PyTorch given
%               user-defined input parameters, plotting progress over time
%               and saving the final trained model to 
%               spectrai/spectrai/saved_models.

            ResetResults(app)
            Training_Options = app.training_options;
            Task_Options = UpdateTaskOptions(app);
            Network_Hyperparameters = SetupNetworkHyperparameters(app);
            Training_Hyperparameters = SetupTrainingHyperparameters(app);
            Preprocessing = SetupPreprocessing(app);
            Data_Augmentation = SetupDataAgumentation(app);
            DataManager_Options = SetupDataManagerOptions(app);
            app.TerminalOutputTextArea.Value = 'Starting Training';
            PrintTrainingDetails(app, 1);
            drawnow;
            
            train_start = tic;
            Train(app, Training_Options, Task_Options, Network_Hyperparameters, ...
                    Training_Hyperparameters, Preprocessing, Data_Augmentation, DataManager_Options)
            train_end = toc(train_start);
            
            app.TerminalOutputTextArea.Value{end+1} = '';
            app.TerminalOutputTextArea.Value{end+1} = ['Total Training Time: ', char(duration([0, 0, train_end]))];
            app.TerminalOutputTextArea.Value{end+1} = '';
            app.TerminalOutputTextArea.Value{end+1} = 'Finished.';
        end

        % Selection changed function: DeepLearningOptionsButtonGroup
        function DeepLearningOptionsButtonGroupSelectionChanged(app, event)
%           Clears existing settings and updates task option.

            ClearDataInputs(app)
            SetupTaskOptions(app);
            SetupDataFormat(app);
        end

        % Selection changed function: 
        % TrainingInferenceOptionsButtonGroup
        function TrainingInferenceOptionsButtonGroupSelectionChanged(app, event)
%           Clears existing settings and updates training/inference option.

            ClearDataInputs(app)
            SetupTrainingOptions(app);
            SetupDataFormat(app);
            UpdateDataLoading(app);
        end

        % Value changed function: SplitDataCheckBox
        function SplitDataCheckBoxValueChanged(app, event)
%           Enables/disables data loading given split data check box value.
% 
%           Description:
%               If split data check box is selected, enables the train,
%               val, and test split edit fields and disables validation and
%               test set loading. Otherwise, disables the train, val, and
%               test split edit fields and enables validation and test set
%               loading.

            if app.SplitDataCheckBox.Value
                app.TrainSplitEditField.Enable = 1;
                app.ValSplitEditField.Enable = 1;
                app.TestSplitEditField.Enable = 1;
            else
                app.TrainSplitEditField.Enable = 0;
                app.ValSplitEditField.Enable = 0;
                app.TestSplitEditField.Enable = 0;
            end
            UpdateDataLoading(app);
            
        end

        % Button pushed function: LoadTrainInputButton
        function LoadTrainInputButtonPushed(app, event)
%           Loads input training data or directory.

            selection = DataInputInstructions(app);
            
            if strcmp(selection, 'Continue')
                app.train_input_data = '';
                app.LoadTrainInputEditField.Value = '';
                loaded_data = LoadInput(app, 'Input');
                try
                    app.train_input_data = loaded_data.pathname;
                    app.LoadTrainInputEditField.Value = loaded_data.pathname;
                    EnablePreview(app);
                catch
                    disp('Error loading input')
                end
            end
        end

        % Button pushed function: LoadValInputButton
        function LoadValInputButtonPushed(app, event)
%           Loads input validation data or directory.

            selection = DataInputInstructions(app);
            
            if strcmp(selection, 'Continue')
                app.val_input_data = '';
                app.LoadValInputEditField.Value = '';
                loaded_data = LoadInput(app, 'Input');
                try
                    app.val_input_data = loaded_data.pathname;
                    app.LoadValInputEditField.Value = loaded_data.pathname;
                    EnablePreview(app);
                catch
                    disp('Error loading input')
                end
            end
        end

        % Button pushed function: LoadTestInputButton
        function LoadTestInputButtonPushed(app, event)
%           Loads input testing data or directory.

            selection = DataInputInstructions(app);
            
            if strcmp(selection, 'Continue')
                app.test_input_data = '';
                app.LoadTestInputEditField.Value = '';
                loaded_data = LoadInput(app, 'Input');
                try
                    app.test_input_data = loaded_data.pathname;
                    app.LoadTestInputEditField.Value = loaded_data.pathname;
                    EnablePreview(app);
                catch
                    disp('Error loading input')
                end
            end
        end

        % Value changed function: ShuffleDataCheckBox
        function ShuffleDataCheckBoxValueChanged(app, event)
%           Enables/disables random shuffle seed given shuffle check box value.

            if app.ShuffleDataCheckBox.Value
                app.SeedCheckBox.Enable = 1;
                app.SeedEditField.Enable = 1;
            else
                app.SeedCheckBox.Enable = 0;
                app.SeedEditField.Enable = 0;
            end
        end

        % Button pushed function: LoadTrainTargetButton
        function LoadTrainTargetButtonPushed(app, event)
%           Loads target training data or directory.

            selection = DataInputInstructions(app);
            
            if strcmp(selection, 'Continue')
                app.train_target_data = '';
                app.LoadTrainTargetEditField.Value = '';
                loaded_data = LoadInput(app, 'Target');
                try
                    app.train_target_data = loaded_data.pathname;
                    app.LoadTrainTargetEditField.Value = loaded_data.pathname;
                    EnablePreview(app);
                catch
                    disp('Error loading input')
                end
            end
        end

        % Button pushed function: LoadValTargetButton
        function LoadValTargetButtonPushed(app, event)
%           Loads target validation data or directory.

            selection = DataInputInstructions(app);
            
            if strcmp(selection, 'Continue')
                app.val_target_data = '';
                app.LoadValTargetEditField.Value = '';
                
                loaded_data = LoadInput(app, 'Target');
                try
                    app.val_target_data = loaded_data.pathname;
                    app.LoadValTargetEditField.Value = loaded_data.pathname;
                    EnablePreview(app);
                catch
                    disp('Error loading input')
                end
            end
        end

        % Button pushed function: LoadTestTargetButton
        function LoadTestTargetButtonPushed(app, event)
%           Loads target testing data or directory.

            selection = DataInputInstructions(app);
            
            if strcmp(selection, 'Continue')
                app.test_target_data = '';
                app.LoadTestTargetEditField.Value = '';
                
                loaded_data = LoadInput(app, 'Target');
                try
                    app.test_target_data = loaded_data.pathname;
                    app.LoadTestTargetEditField.Value = loaded_data.pathname;
                    EnablePreview(app);
                catch
                    disp('Error loading input')
                end
            end
        end

        % Button pushed function: ClearAllDataButton
        function ClearAllDataButtonPushed(app, event)
%           Clears input data and data augmentation selections.

            msg = {'Clear All Data?'};
            title = 'Confirm Clear All Data';
            selection = uiconfirm(app.spectraiUIFigure,msg,title,...
                       'Options',{'OK', 'Cancel'},...
                       'DefaultOption',2,'CancelOption',2);
            
            if strcmp(selection, 'OK')
                ClearDataInputs(app)
                
                app.TerminalOutputTextArea.Value = '';
                reset(app.InputDisplay);
                reset(app.OutputDisplay);
                reset(app.TrainingProgressDisplay);
                cla(app.InputDisplay);
                cla(app.OutputDisplay);
                cla(app.TrainingProgressDisplay);
                legend(app.TrainingProgressDisplay, 'off');
                
                app.LoadNetworkEditField.Value = '';
                app.pretrained_network = '';
                app.pretrained_classes = [];
                app.epochs = [];
                app.train_loss = [];
                app.val_loss = [];
                
                EnableHyperparameters(app, 1)
            end
        end

        % Selection changed function: DataFormatButtonGroup
        function DataFormatButtonGroupSelectionChanged(app, event)
%           Clears existing settings and updates data format option.

            ClearDataInputs(app)
            SetupDataFormat(app)     
        end

        % Value changed function: DataAsDirectoryCheckBox
        function DataAsDirectoryCheckBoxValueChanged(app, event)
%           Updates the data_driectory flag given checkbox value.

            if app.DataAsDirectoryCheckBox.Value
                app.data_directory = 'True';
            else
                app.data_directory = 'False';
            end
        end

        % Value changed function: TrainSplitEditField
        function TrainSplitEditFieldValueChanged(app, event)
%           Updates the test split value to ensure all splits sum to 100.
%
%           Description:
%               Given a change in the val split value, updates the test
%               split value to ensure all splits sum to 100.

            app.TestSplitEditField.Value = 100 - app.TrainSplitEditField.Value - app.ValSplitEditField.Value;
        end

        % Value changed function: ValSplitEditField
        function ValSplitEditFieldValueChanged(app, event)
%           Updates the test split value to ensure all splits sum to 100.
%
%           Description:
%               Given a change in the val split value, updates the test
%               split value to ensure all splits sum to 100.

            app.TestSplitEditField.Value = 100 - app.TrainSplitEditField.Value - app.ValSplitEditField.Value;
        end

        % Button pushed function: PreviewSpectraButton
        function PreviewSpectraButtonPushed(app, event)
%           Displays an input spectrum and corresponding target output.
% 
%           Description:
%               Given an input sample and corresponding target sample, from
%               a dataset, preprocesses the sample (including any data
%               augmentation) and returns the preprocessed data. Then, 
%               plots the input spectrum (or a random spectrum from an
%               input hyperspectral image) and also displays the
%               corresponding target (either a spectrum, a class, a 
%               segmentation mask, or a hyperspectral image).

            Task_Options = UpdateTaskOptions(app);
            Network_Hyperparameters = SetupNetworkHyperparameters(app);
            Training_Hyperparameters = SetupTrainingHyperparameters(app);
            Preprocessing_List = SetupPreprocessing(app);
            Augmentation_List = SetupDataAgumentation(app);
            DataManager_Options = SetupDataManagerOptions(app);
            output = py.previewer.preview_preprocessing(Task_Options, Network_Hyperparameters, Training_Hyperparameters, Preprocessing_List, Augmentation_List, DataManager_Options);
            x = double(output{'input'});
            y = double(output{'target'});
            PreviewSpectra(app, x, y, 'Input', 'Target')
        end

        % Value changed function: SpectralShiftCheckBox
        function SpectralShiftCheckBoxValueChanged(app, event)
%           Enables/disables spectral shift edit field given check box value.

            if app.SpectralShiftCheckBox.Value
                app.SpectralShiftEditField.Enable = 1;
            else
                app.SpectralShiftEditField.Enable = 0;
            end
        end

        % Button pushed function: LoadNetworkButton
        function LoadNetworkButtonPushed(app, event)
%           Loads a neural network pre-trained using spectrai.
% 
%           Description:
%               Prompts the user to select a pre-trained neural network to
%               load for evaluation or transfer learning.

            ClearDataInputs(app);
            SetupTrainingOptions(app);
        end

        % Value changed function: SpectralCropStartEditField
        function SpectralCropStartEditFieldValueChanged(app, event)
%           Updates the spectral crop end value given spectrum length.
%
%           Description:
%               Given a change in the spectral crop start value, updates
%               the spectral crop end value to be greater than the spectral
%               crop start value by the spectrum length value.

            app.SpectralCropEndEditField.Value = app.SpectralCropStartEditField.Value + app.SpectrumLengthEditField.Value;
        end

        % Value changed function: SpectralCropEndEditField
        function SpectralCropEndEditFieldValueChanged(app, event)
%           Updates the spectral crop start value given spectrum length.
%
%           Description:
%               Given a change in the spectral crop end value, updates
%               the spectral crop start value to be less than the spectral
%               crop end value by the spectrum length value. Performs check
%               to ensure spectral crop end value remains less than
%               spectrum length.

            if app.SpectralCropEndEditField.Value < app.SpectrumLengthEditField.Value
                app.SpectralCropEndEditField.Value = app.SpectrumLengthEditField.Value;
                msg = {'The Spectral Crop End Value must be greater than or equal to the Spectrum Length'};
                title = 'Spectral Crop End Value';
                uiconfirm(app.spectraiUIFigure,msg,title,'Options',{'Continue'},'DefaultOption',1,'Icon','warning');
            end
            app.SpectralCropStartEditField.Value = app.SpectralCropEndEditField.Value - app.SpectrumLengthEditField.Value;
        end

        % Value changed function: SpectrumLengthEditField
        function SpectrumLengthEditFieldValueChanged(app, event)
%           Resets the spectral crop start and end values given spectrum length.

            app.SpectralCropStartEditField.Value = 0;
            app.SpectralCropEndEditField.Value = app.SpectrumLengthEditField.Value;
        end

        % Button pushed function: PreviewImagesButton
        function PreviewImagesButtonPushed(app, event)
%           Displays an input spectrum and corresponding target output.
% 
%           Description:
%               Given an input sample and corresponding target sample from
%               a dataset, preprocesses the sample (including any data
%               augmentation) and returns the preprocessed data. Then, 
%               displays the input image and also displays the 
%               corresponding target (either a class, a segmentation mask, 
%               or a hyperspectral image).

            Task_Options = UpdateTaskOptions(app);
            Network_Hyperparameters = SetupNetworkHyperparameters(app);
            Training_Hyperparameters = SetupTrainingHyperparameters(app);
            Preprocessing_List = SetupPreprocessing(app);
            Augmentation_List = SetupDataAgumentation(app);
            DataManager_Options = SetupDataManagerOptions(app);
            output = py.previewer.preview_preprocessing(Task_Options, Network_Hyperparameters, Training_Hyperparameters, Preprocessing_List, Augmentation_List, DataManager_Options);
            x = double(output{'input'});
            y = double(output{'target'});
            PreviewImage(app, x, y, 'Input', 'Target')
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
%           Applies a neural network in PyTorch given input parameters.
% 
%           Description:
%               Performs neural network application in PyTorch given
%               user-defined input parameters, and saving the application  
%               outputs to spectrai/spectrai/apply_outputs.

            ResetResults(app)
            Training_Options = app.training_options;
            Task_Options = UpdateTaskOptions(app);
            Network_Hyperparameters = SetupNetworkHyperparameters(app);
            Training_Hyperparameters = SetupTrainingHyperparameters(app);
            Preprocessing = SetupPreprocessing(app);
            Data_Augmentation = SetupDataAgumentation(app);
            DataManager_Options = SetupDataManagerOptions(app);
            app.TerminalOutputTextArea.Value = 'Applying Pre-trained Network';
            PrintTrainingDetails(app, 0);
            drawnow;
            
            Apply(app, Training_Options, Task_Options, Network_Hyperparameters, ...
                    Training_Hyperparameters, Preprocessing, Data_Augmentation, DataManager_Options)
            app.TerminalOutputTextArea.Value{end+1} = '';
            app.TerminalOutputTextArea.Value{end+1} = 'Finished.';
        end

        % Value changed function: ReportFreqEpochsEditField
        function ReportFreqEpochsEditFieldValueChanged(app, event)
%           Updates the report frequency (epochs) value.

            if app.ReportFreqEpochsEditField.Value >= app.EpochsEditField.Value
                app.ReportFreqEpochsEditField.Value = app.EpochsEditField.Value-1;
                msg = {'Report frequency must be less than the number of epochs.'};
                title = 'Report Frequency';
                uiconfirm(app.spectraiUIFigure,msg,title,'Options',{'Continue'},'DefaultOption',1,'Icon','warning');
            end
        end

        % Value changed function: SaveFreqEpochsEditField
        function SaveFreqEpochsEditFieldValueChanged(app, event)
%           Updates the save frequency (epochs) value.

            if app.SaveFreqEpochsEditField.Value > app.EpochsEditField.Value
                app.SaveFreqEpochsEditField.Value = app.EpochsEditField.Value;
                msg = {'Save frequency must be less than or equal to the number of epochs.'; 'A value of zero will not save output.'};
                title = 'Save Frequency';
                uiconfirm(app.spectraiUIFigure,msg,title,'Options',{'Continue'},'DefaultOption',1,'Icon','warning');
            end
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
%           Evaluates a neural network in PyTorch given input parameters.
% 
%           Description:
%               Performs neural network evaluation in PyTorch given
%               user-defined input parameters.

            ResetResults(app)
            Training_Options = app.training_options;
            Task_Options = UpdateTaskOptions(app);
            Network_Hyperparameters = SetupNetworkHyperparameters(app);
            Training_Hyperparameters = SetupTrainingHyperparameters(app);
            Preprocessing = SetupPreprocessing(app);
            Data_Augmentation = SetupDataAgumentation(app);
            DataManager_Options = SetupDataManagerOptions(app);
            app.TerminalOutputTextArea.Value = 'Starting Evaluation';
            PrintTrainingDetails(app, 0);
            drawnow;
                        
            Evaluate(app, Training_Options, Task_Options, Network_Hyperparameters, ...
                    Training_Hyperparameters, Preprocessing, Data_Augmentation, DataManager_Options)
            app.TerminalOutputTextArea.Value{end+1} = '';
            app.TerminalOutputTextArea.Value{end+1} = 'Finished.';
        end

        % Value changed function: NetworkDropDown
        function NetworkDropDownValueChanged(app, event)
            Enable3DNetwork(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.spectraiUIFigure = uifigure('Visible', 'off');
            app.spectraiUIFigure.Name = 'spectrai';
            app.TrainingInferencePanel = uipanel(app.spectraiUIFigure);
            app.TrainingInferencePanel.Title = '1. Training/Inference';
            app.TrainingInferenceOptionsButtonGroup = uibuttongroup(app.TrainingInferencePanel);
            app.TrainingInferenceOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrainingInferenceOptionsButtonGroupSelectionChanged, true);
            app.TrainingInferenceOptionsButtonGroup.Title = 'Training/Inference Options';
            app.ApplyPreTrainedNetworkButton = uiradiobutton(app.TrainingInferenceOptionsButtonGroup);
            app.ApplyPreTrainedNetworkButton.Text = 'Apply Pre-Trained Network';
            app.TransferLearningButton = uiradiobutton(app.TrainingInferenceOptionsButtonGroup);
            app.TransferLearningButton.Text = 'Transfer Learning';
            app.TrainFromScratchButton = uiradiobutton(app.TrainingInferenceOptionsButtonGroup);
            app.TrainFromScratchButton.Text = 'Train From Scratch';
            app.TrainFromScratchButton.Value = true;
            app.LoadNetworkButton = uibutton(app.TrainingInferencePanel, 'push');
            app.LoadNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButtonPushed, true);
            app.LoadNetworkButton.Text = 'Load Network';
            app.LoadNetworkEditField = uieditfield(app.TrainingInferencePanel, 'text');
            app.DeepLearningTaskPanel = uipanel(app.spectraiUIFigure);
            app.DeepLearningTaskPanel.Title = '2. Deep Learning Task';
            app.DeepLearningOptionsButtonGroup = uibuttongroup(app.DeepLearningTaskPanel);
            app.DeepLearningOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DeepLearningOptionsButtonGroupSelectionChanged, true);
            app.DeepLearningOptionsButtonGroup.Title = 'Deep Learning Options';
            app.DenoisingButton = uiradiobutton(app.DeepLearningOptionsButtonGroup);
            app.DenoisingButton.Text = 'Denoising';
            app.DenoisingButton.Value = true;
            app.SuperResolutionButton = uiradiobutton(app.DeepLearningOptionsButtonGroup);
            app.SuperResolutionButton.Text = 'Super-Resolution';
            app.SegmentationButton = uiradiobutton(app.DeepLearningOptionsButtonGroup);
            app.SegmentationButton.Text = 'Segmentation';
            app.ClassificationButton = uiradiobutton(app.DeepLearningOptionsButtonGroup);
            app.ClassificationButton.Text = 'Classification';
            app.CalibrationButton = uiradiobutton(app.DeepLearningOptionsButtonGroup);
            app.CalibrationButton.Text = 'Calibration';
            app.ClassesEditFieldLabel = uilabel(app.DeepLearningOptionsButtonGroup);
            app.ClassesEditFieldLabel.Text = 'Classes';
            app.ClassesEditField = uieditfield(app.DeepLearningOptionsButtonGroup, 'numeric');
            app.ClassesEditField.Limits = [0 Inf];
            app.ClassesEditField.Value = 5;
            app.DataManagerPanel = uipanel(app.spectraiUIFigure);
            app.DataManagerPanel.Title = '3. Data Manager';
            app.LoadTrainInputButton = uibutton(app.DataManagerPanel, 'push');
            app.LoadTrainInputButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainInputButtonPushed, true);
            app.LoadTrainInputButton.Text = 'Load Train Input';
            app.LoadValInputButton = uibutton(app.DataManagerPanel, 'push');
            app.LoadValInputButton.ButtonPushedFcn = createCallbackFcn(app, @LoadValInputButtonPushed, true);
            app.LoadValInputButton.Text = 'Load Val Input';
            app.LoadTestInputButton = uibutton(app.DataManagerPanel, 'push');
            app.LoadTestInputButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestInputButtonPushed, true);
            app.LoadTestInputButton.Text = 'Load Test Input';
            app.ClearAllDataButton = uibutton(app.DataManagerPanel, 'push');
            app.ClearAllDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllDataButtonPushed, true);
            app.ClearAllDataButton.Text = 'Clear All Data';
            app.ShuffleDataCheckBox = uicheckbox(app.DataManagerPanel);
            app.ShuffleDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShuffleDataCheckBoxValueChanged, true);
            app.ShuffleDataCheckBox.Text = 'Shuffle Data';
            app.SplitDataCheckBox = uicheckbox(app.DataManagerPanel);
            app.SplitDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @SplitDataCheckBoxValueChanged, true);
            app.SplitDataCheckBox.Text = 'Split Data';
            app.TrainSplitEditFieldLabel = uilabel(app.DataManagerPanel);
            app.TrainSplitEditFieldLabel.Text = 'Train Split';
            app.TrainSplitEditField = uieditfield(app.DataManagerPanel, 'numeric');
            app.TrainSplitEditField.Limits = [0 100];
            app.TrainSplitEditField.ValueChangedFcn = createCallbackFcn(app, @TrainSplitEditFieldValueChanged, true);
            app.TrainSplitEditField.Value = 75;
            app.ValSplitEditFieldLabel = uilabel(app.DataManagerPanel);
            app.ValSplitEditFieldLabel.Text = 'Val Split';
            app.ValSplitEditField = uieditfield(app.DataManagerPanel, 'numeric');
            app.ValSplitEditField.Limits = [0 100];
            app.ValSplitEditField.ValueChangedFcn = createCallbackFcn(app, @ValSplitEditFieldValueChanged, true);
            app.ValSplitEditField.Value = 15;
            app.TestSplitEditFieldLabel = uilabel(app.DataManagerPanel);
            app.TestSplitEditFieldLabel.Text = 'Test Split';
            app.TestSplitEditField = uieditfield(app.DataManagerPanel, 'numeric');
            app.TestSplitEditField.Limits = [0 100];
            app.TestSplitEditField.Editable = 'off';
            app.TestSplitEditField.Value = 10;
            app.DataAsDirectoryCheckBox = uicheckbox(app.DataManagerPanel);
            app.DataAsDirectoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataAsDirectoryCheckBoxValueChanged, true);
            app.DataAsDirectoryCheckBox.Tooltip = {'Untick if data is stored as a single file.'};
            app.DataAsDirectoryCheckBox.Text = 'Data As Directory';
            app.DataAsDirectoryCheckBox.Value = true;
            app.SeedEditField = uieditfield(app.DataManagerPanel, 'numeric');
            app.SeedEditField.Limits = [0 Inf];
            app.SeedCheckBox = uicheckbox(app.DataManagerPanel);
            app.SeedCheckBox.Text = 'Seed';
            app.LoadTrainTargetButton = uibutton(app.DataManagerPanel, 'push');
            app.LoadTrainTargetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainTargetButtonPushed, true);
            app.LoadTrainTargetButton.Text = 'Load Train Target';
            app.LoadTrainInputEditField = uieditfield(app.DataManagerPanel, 'text');
            app.LoadTrainTargetEditField = uieditfield(app.DataManagerPanel, 'text');
            app.LoadValTargetButton = uibutton(app.DataManagerPanel, 'push');
            app.LoadValTargetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadValTargetButtonPushed, true);
            app.LoadValTargetButton.Text = 'Load Val Target';
            app.LoadValInputEditField = uieditfield(app.DataManagerPanel, 'text');
            app.LoadValTargetEditField = uieditfield(app.DataManagerPanel, 'text');
            app.LoadTestTargetButton = uibutton(app.DataManagerPanel, 'push');
            app.LoadTestTargetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestTargetButtonPushed, true);
            app.LoadTestTargetButton.Text = 'Load Test Target';
            app.LoadTestInputEditField = uieditfield(app.DataManagerPanel, 'text');
            app.LoadTestTargetEditField = uieditfield(app.DataManagerPanel, 'text');
            app.DataFormatButtonGroup = uibuttongroup(app.DataManagerPanel);
            app.DataFormatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataFormatButtonGroupSelectionChanged, true);
            app.DataFormatButtonGroup.Title = 'Data Format';
            app.ImageHWCButton = uiradiobutton(app.DataFormatButtonGroup);
            app.ImageHWCButton.Tooltip = {'Image: Height, Width, Channels (Spectral Bands)'};
            app.ImageHWCButton.Text = 'Image: H, W, C';
            app.ImageHWCButton.Value = true;
            app.ImageCHWButton = uiradiobutton(app.DataFormatButtonGroup);
            app.ImageCHWButton.Tooltip = {'Image: Channels (Spectral Bands), Height, Width'};
            app.ImageCHWButton.Text = 'Image: C, H, W';
            app.SpectraButton = uiradiobutton(app.DataFormatButtonGroup);
            app.SpectraButton.Text = 'Spectra';
            app.TrainingHyperparametersPanel = uipanel(app.spectraiUIFigure);
            app.TrainingHyperparametersPanel.Title = '4. Training Hyperparameters';
            app.EpochsEditFieldLabel = uilabel(app.TrainingHyperparametersPanel);
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.TrainingHyperparametersPanel, 'numeric');
            app.EpochsEditField.Limits = [0 Inf];
            app.EpochsEditField.Value = 3;
            app.BatchSizeEditFieldLabel = uilabel(app.TrainingHyperparametersPanel);
            app.BatchSizeEditFieldLabel.Text = 'Batch Size';
            app.BatchSizeEditField = uieditfield(app.TrainingHyperparametersPanel, 'numeric');
            app.BatchSizeEditField.Limits = [1 Inf];
            app.BatchSizeEditField.Value = 2;
            app.LearningRateEditFieldLabel = uilabel(app.TrainingHyperparametersPanel);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.LearningRateEditField = uieditfield(app.TrainingHyperparametersPanel, 'numeric');
            app.LearningRateEditField.Limits = [0 1];
            app.LearningRateEditField.Value = 0.0001;
            app.InputImageSizeEditFieldLabel = uilabel(app.TrainingHyperparametersPanel);
            app.InputImageSizeEditFieldLabel.Text = 'Input Image Size';
            app.InputImageSizeEditField = uieditfield(app.TrainingHyperparametersPanel, 'numeric');
            app.InputImageSizeEditField.Limits = [1 Inf];
            app.InputImageSizeEditField.Value = 16;
            app.TargetImageSizeEditFieldLabel = uilabel(app.TrainingHyperparametersPanel);
            app.TargetImageSizeEditFieldLabel.Text = 'Target Image Size';
            app.TargetImageSizeEditField = uieditfield(app.TrainingHyperparametersPanel, 'numeric');
            app.TargetImageSizeEditField.Limits = [1 Inf];
            app.TargetImageSizeEditField.Value = 64;
            app.SpectrumLengthEditFieldLabel = uilabel(app.TrainingHyperparametersPanel);
            app.SpectrumLengthEditFieldLabel.Text = 'Spectrum Length';
            app.SpectrumLengthEditField = uieditfield(app.TrainingHyperparametersPanel, 'numeric');
            app.SpectrumLengthEditField.Limits = [1 Inf];
            app.SpectrumLengthEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrumLengthEditFieldValueChanged, true);
            app.SpectrumLengthEditField.Value = 500;
            app.OptimizerDropDownLabel = uilabel(app.TrainingHyperparametersPanel);
            app.OptimizerDropDownLabel.Text = 'Optimizer';
            app.OptimizerDropDown = uidropdown(app.TrainingHyperparametersPanel);
            app.OptimizerDropDown.Items = {'Adam', 'Adagrad', 'SGD', 'RMSprop'};
            app.OptimizerDropDown.Value = 'Adam';
            app.LRSchedulerDropDownLabel = uilabel(app.TrainingHyperparametersPanel);
            app.LRSchedulerDropDownLabel.Text = 'LR Scheduler';
            app.LRSchedulerDropDown = uidropdown(app.TrainingHyperparametersPanel);
            app.LRSchedulerDropDown.Items = {'Constant', 'Step', 'Multiplicative', 'Cyclic', 'OneCycle', 'ReduceOnPlateau'};
            app.LRSchedulerDropDown.Value = 'Constant';
            app.CriterionDropDownLabel = uilabel(app.TrainingHyperparametersPanel);
            app.CriterionDropDownLabel.Text = 'Criterion';
            app.CriterionDropDown = uidropdown(app.TrainingHyperparametersPanel);
            app.CriterionDropDown.Items = {'L1', 'L2 / MSE', 'Cross Entropy', 'Binary Cross Entropy'};
            app.CriterionDropDown.Value = 'L1';
            app.NetworkHyperparametersPanel = uipanel(app.spectraiUIFigure);
            app.NetworkHyperparametersPanel.Title = '5. Network Hyperparameters';
            app.NetworkDropDownLabel = uilabel(app.NetworkHyperparametersPanel);
            app.NetworkDropDownLabel.Text = 'Network';
            app.NetworkDropDown = uidropdown(app.NetworkHyperparametersPanel);
            app.NetworkDropDown.Items = {'ResUNet', 'UNet', 'ResNet', 'RCAN'};
            app.NetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkDropDownValueChanged, true);
            app.NetworkDropDown.Value = 'ResUNet';
            app.ActivationDropDownLabel = uilabel(app.NetworkHyperparametersPanel);
            app.ActivationDropDownLabel.Text = 'Activation';
            app.ActivationDropDown = uidropdown(app.NetworkHyperparametersPanel);
            app.ActivationDropDown.Items = {'ReLU', 'LeakyReLU', 'PReLU'};
            app.ActivationDropDown.Value = 'ReLU';
            app.NormalizationDropDownLabel = uilabel(app.NetworkHyperparametersPanel);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.NormalizationDropDown = uidropdown(app.NetworkHyperparametersPanel);
            app.NormalizationDropDown.Items = {'BatchNorm', 'LayerNorm', 'InstanceNorm', 'GroupNorm', 'None'};
            app.NormalizationDropDown.Value = 'BatchNorm';
            app.DimSwitch = uiswitch(app.NetworkHyperparametersPanel, 'slider');
            app.DimSwitch.Items = {'2D', '3D'};
            app.DimSwitch.Enable = 'off';
            app.DimSwitch.Tooltip = {'Apply 2D or 3D convolutions.'};
            app.DimSwitch.Value = '2D';
            app.TerminalOutputPanel = uipanel(app.spectraiUIFigure);
            app.TerminalOutputPanel.Title = 'Terminal Output';
            app.TerminalOutputTextArea = uitextarea(app.TerminalOutputPanel);
            app.TerminalOutputTextArea.Editable = 'off';
            app.TrainButton = uibutton(app.TerminalOutputPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.ApplyButton = uibutton(app.TerminalOutputPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Tooltip = {'Apply a pre-trained network against a test set for which there does not exist target data.'};
            app.ApplyButton.Text = 'Apply';
            app.ResultsPreviewCheckBox = uicheckbox(app.TerminalOutputPanel);
            app.ResultsPreviewCheckBox.Text = 'Results Preview';
            app.ResultsPreviewCheckBox.Value = true;
            app.ReportFreqEpochsEditFieldLabel = uilabel(app.TerminalOutputPanel);
            app.ReportFreqEpochsEditFieldLabel.Text = 'Report Freq. (Epochs)';
            app.ReportFreqEpochsEditField = uieditfield(app.TerminalOutputPanel, 'numeric');
            app.ReportFreqEpochsEditField.Limits = [1 Inf];
            app.ReportFreqEpochsEditField.ValueChangedFcn = createCallbackFcn(app, @ReportFreqEpochsEditFieldValueChanged, true);
            app.ReportFreqEpochsEditField.Value = 1;
            app.SaveFreqEpochsEditFieldLabel = uilabel(app.TerminalOutputPanel);
            app.SaveFreqEpochsEditFieldLabel.Text = 'Save Freq. (Epochs)';
            app.SaveFreqEpochsEditField = uieditfield(app.TerminalOutputPanel, 'numeric');
            app.SaveFreqEpochsEditField.Limits = [0 Inf];
            app.SaveFreqEpochsEditField.ValueChangedFcn = createCallbackFcn(app, @SaveFreqEpochsEditFieldValueChanged, true);
            app.EvaluateButton = uibutton(app.TerminalOutputPanel, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Tooltip = {'Evaluate a pre-trained network against a test set for which there does exist target data.'};
            app.EvaluateButton.Text = 'Evaluate';
            app.spectraiLabel = uilabel(app.spectraiUIFigure);
            app.spectraiLabel.Text = 'spectrai';
            app.DataAugmentationPanel = uipanel(app.spectraiUIFigure);
            app.DataAugmentationPanel.Title = '6. Data Augmentation';
            app.HorizontalFlipCheckBox = uicheckbox(app.DataAugmentationPanel);
            app.HorizontalFlipCheckBox.Text = 'Horizontal Flip';
            app.VerticalFlipCheckBox = uicheckbox(app.DataAugmentationPanel);
            app.VerticalFlipCheckBox.Text = 'Vertical Flip';
            app.RotationCheckBox = uicheckbox(app.DataAugmentationPanel);
            app.RotationCheckBox.Text = 'Rotation';
            app.RandomCropCheckBox = uicheckbox(app.DataAugmentationPanel);
            app.RandomCropCheckBox.Text = 'Random Crop';
            app.SpectralShiftCheckBox = uicheckbox(app.DataAugmentationPanel);
            app.SpectralShiftCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpectralShiftCheckBoxValueChanged, true);
            app.SpectralShiftCheckBox.Text = 'Spectral Shift';
            app.SpectralFlipCheckBox = uicheckbox(app.DataAugmentationPanel);
            app.SpectralFlipCheckBox.Text = 'Spectral Flip';
            app.SpectralBackgroundCheckBox = uicheckbox(app.DataAugmentationPanel);
            app.SpectralBackgroundCheckBox.Text = 'Spectral Background';
            app.MixupCheckBox = uicheckbox(app.DataAugmentationPanel);
            app.MixupCheckBox.Text = 'Mixup';
            app.SpectralShiftEditField = uieditfield(app.DataAugmentationPanel, 'numeric');
            app.SpectralShiftEditField.Limits = [0 1];
            app.SpectralShiftEditField.Enable = 'off';
            app.PreprocessingPanel = uipanel(app.spectraiUIFigure);
            app.PreprocessingPanel.Title = '7. Preprocessing';
            app.SpectralCropEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.SpectralCropEditFieldLabel.Text = 'Spectral Crop';
            app.SpectralCropStartEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.SpectralCropStartEditField.Limits = [0 Inf];
            app.SpectralCropStartEditField.ValueChangedFcn = createCallbackFcn(app, @SpectralCropStartEditFieldValueChanged, true);
            app.BGSubtractionDropDownLabel = uilabel(app.PreprocessingPanel);
            app.BGSubtractionDropDownLabel.Text = 'BG Subtraction';
            app.BGSubtractionDropDown = uidropdown(app.PreprocessingPanel);
            app.BGSubtractionDropDown.Items = {'None', 'Asymmetric Least Squares', '3rd Order Polynomial', '5th Order Polynomial', 'Minimum Value Offset'};
            app.BGSubtractionDropDown.Value = 'None';
            app.DataNormalizationDropDownLabel = uilabel(app.PreprocessingPanel);
            app.DataNormalizationDropDownLabel.Text = 'Data Normalization';
            app.DataNormalizationDropDown = uidropdown(app.PreprocessingPanel);
            app.DataNormalizationDropDown.Items = {'None', 'Max Value', 'Area Under The Curve'};
            app.DataNormalizationDropDown.Value = 'None';
            app.toEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.toEditFieldLabel.Text = 'to';
            app.SpectralCropEndEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.SpectralCropEndEditField.Limits = [1 Inf];
            app.SpectralCropEndEditField.ValueChangedFcn = createCallbackFcn(app, @SpectralCropEndEditFieldValueChanged, true);
            app.SpectralCropEndEditField.Value = 500;
            app.PreviewSpectraButton = uibutton(app.PreprocessingPanel, 'push');
            app.PreviewSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewSpectraButtonPushed, true);
            app.PreviewSpectraButton.Enable = 'off';
            app.PreviewSpectraButton.Text = 'Preview Spectra';
            app.PreviewImagesButton = uibutton(app.PreprocessingPanel, 'push');
            app.PreviewImagesButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewImagesButtonPushed, true);
            app.PreviewImagesButton.Enable = 'off';
            app.PreviewImagesButton.Text = 'Preview Images';
            app.InputDisplay = uiaxes(app.spectraiUIFigure);
            title(app.InputDisplay, 'Input')
            app.InputDisplay.XTick = [];
            app.InputDisplay.YTick = [];
            app.TrainingProgressDisplay = uiaxes(app.spectraiUIFigure);
            xlabel(app.TrainingProgressDisplay, 'Epochs')
            ylabel(app.TrainingProgressDisplay, 'Loss')
            zlabel(app.TrainingProgressDisplay, 'Z')
            app.TrainingProgressDisplay.XTick = [];
            app.TrainingProgressDisplay.YTick = [];
            app.OutputDisplay = uiaxes(app.spectraiUIFigure);
            title(app.OutputDisplay, 'Output')
            app.OutputDisplay.XTick = [];
            app.OutputDisplay.YTick = [];
            % Show the figure after all components are created
            app.spectraiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spectrai

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.spectraiUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.spectraiUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.spectraiUIFigure)
        end
    end
end